classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HelpMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        TabGroup2                      matlab.ui.container.TabGroup
        ResultTab                      matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        OptimizedGraph                 matlab.ui.control.UIAxes
        NonOptimizedGraph              matlab.ui.control.UIAxes
        DailyBatteryChangeTab          matlab.ui.container.Tab
        DailyBatteryGraph              matlab.ui.control.UIAxes
        DailyTapChangerChangeTab       matlab.ui.container.Tab
        DailyTapChangerGraph           matlab.ui.control.UIAxes
        PreviewPanel                   matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        HTML                           matlab.ui.control.HTML
        Panel                          matlab.ui.container.Panel
        Panel_2                        matlab.ui.container.Panel
        TimeDropDown                   matlab.ui.control.DropDown
        TimeDropDownLabel              matlab.ui.control.Label
        IterationSpinner               matlab.ui.control.Spinner
        IterationSpinnerLabel          matlab.ui.control.Label
        SearchAgentsSpinner            matlab.ui.control.Spinner
        SearchAgentsSpinnerLabel       matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        DistributionGridsTab           matlab.ui.container.Tab
        ResetSelectedDGsButton         matlab.ui.control.Button
        DGperbusSpinner                matlab.ui.control.Spinner
        DGperbusSpinnerLabel           matlab.ui.control.Label
        SelectedTextArea               matlab.ui.control.TextArea
        SelectedTextAreaLabel          matlab.ui.control.Label
        LocationsListBox               matlab.ui.control.ListBox
        LocationsListBoxLabel          matlab.ui.control.Label
        MinMaxLabel                    matlab.ui.control.Label
        TapChangersTab                 matlab.ui.container.Tab
        ResetSelectedTapChangerButton  matlab.ui.control.Button
        SelectedTapChangerTextArea     matlab.ui.control.TextArea
        SelectedTapChangerTextAreaLabel  matlab.ui.control.Label
        TapChangerMinMaxLabel          matlab.ui.control.Label
        TapChangerLocationListBox      matlab.ui.control.ListBox
        TapChangerLocationLabel        matlab.ui.control.Label
        BatterySettingsTab             matlab.ui.container.Tab
        ThesesettingsareonlyavailableforbatterytapchangermodeLabel  matlab.ui.control.Label
        BatterySettingsPanel           matlab.ui.container.Panel
        InitialOutputkWSpinner         matlab.ui.control.Spinner
        InitialOutputkWSpinnerLabel    matlab.ui.control.Label
        SoCUBkWSpinner                 matlab.ui.control.Spinner
        SoCUBkWSpinnerLabel            matlab.ui.control.Label
        SoCLBkWSpinner                 matlab.ui.control.Spinner
        SoCLBkWSpinnerLabel            matlab.ui.control.Label
        StepkWhSpinner                 matlab.ui.control.Spinner
        StepkWhSpinnerLabel            matlab.ui.control.Label
        HourlyLoadPVProfilePanel       matlab.ui.container.Panel
        SelectButton_2                 matlab.ui.control.Button
        HourlyPVLoadProfileFileField   matlab.ui.control.EditField
        HourlyLoadProfilePanel         matlab.ui.container.Panel
        SelectButton                   matlab.ui.control.Button
        HourlyLoadProfileFileField     matlab.ui.control.EditField
        ModeButtonGroup                matlab.ui.container.ButtonGroup
        BatteryTapChangerButton        matlab.ui.control.RadioButton
        DGTapChangerButton             matlab.ui.control.RadioButton
        AlgorithmButtonGroup           matlab.ui.container.ButtonGroup
        PSOButton                      matlab.ui.control.RadioButton
        ALOButton                      matlab.ui.control.RadioButton
        WOAButton                      matlab.ui.control.RadioButton
        GWOButton                      matlab.ui.control.RadioButton
        LoadingImage                   matlab.ui.control.Image
        RunButton                      matlab.ui.control.Button
        TestSystemButtonGroup          matlab.ui.container.ButtonGroup
        BusButton_3                    matlab.ui.control.RadioButton
        BusButton_2                    matlab.ui.control.RadioButton
        BusButton                      matlab.ui.control.RadioButton
    end

    
    methods (Access = public)
        
        function resetLocationListBoxes(app,range,tcRange,type)
            %type = 1 dg only
            %type = 2 tc only
            %type = 0 all
            
            global bus_minSelect
            global bus_maxSelect
            global tapChanger_minSelect
            global tapChanger_maxSelect
            
            if type == 1 || type == 0
                % set dg bus location list
                set(app.LocationsListBox, 'value',{}) ;
                app.LocationsListBox.Items = cellstr(range);
                % hide selected dg buses screen
                app.SelectedTextArea.Visible = 0;
                app.SelectedTextAreaLabel.Visible = 0;
                % Set dg bus min and max limit text
                app.MinMaxLabel.Text = sprintf('Min: %d, Max: %d', bus_minSelect, bus_maxSelect);
            end
            
            if type == 2 || type == 0
                % set tap changer list
                set(app.TapChangerLocationListBox, 'value',{}) ;
                app.TapChangerLocationListBox.Items = cellstr(tcRange);
                % hide selected tap changers screen
                app.SelectedTapChangerTextArea.Visible = 0;
                app.SelectedTapChangerTextAreaLabel.Visible = 0;
                % Set tap changer min and max limit text
                app.TapChangerMinMaxLabel.Text = sprintf('Min: %d, Max: %d', tapChanger_minSelect, tapChanger_maxSelect);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global bus_minSelect
            global bus_maxSelect
            global tapChanger_minSelect
            global tapChanger_maxSelect
            global maxBus
            global testCase
            global currentMode
            global testCaseFriendlyText
            
            maxBus = 33;
            
            bus_minSelect = 3;
            bus_maxSelect = 6;
            
            
            tapChanger_minSelect = 0;
            tapChanger_maxSelect = 3;
            
            currentMode = '1';
            
            testCase = 'case33bw';
            testCaseFriendlyText = '33 Bus';
            
            resetLocationListBoxes(app,string(1:maxBus),string(2:maxBus),0);
            
            app.HTML.HTMLSource = strcat('CasePreview/preview-',testCase,'.html');
            
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            global bus_minSelect
            global bus_maxSelect
            global tapChanger_minSelect
            global tapChanger_maxSelect
            global testCase
            global testCaseFriendlyText
            global dayTimeLoads
            global dayTimePvOutputLoadVal
            global currentMode
            
            debugMode = false
                        
            if(currentMode == "1" && length(app.LocationsListBox.Value) < bus_minSelect || length(app.LocationsListBox.Value) > bus_maxSelect)
                msgbox(sprintf("You can select a minimum of %d and a maximum of %d DG locations.", bus_minSelect, bus_maxSelect));
            elseif(currentMode == "1" && length(app.TapChangerLocationListBox.Value) < tapChanger_minSelect || length(app.TapChangerLocationListBox.Value) > tapChanger_maxSelect)
                msgbox(sprintf("You can select a minimum of %d and a maximum of %d tap changer locations.", tapChanger_minSelect, tapChanger_maxSelect));
            elseif(currentMode == "2" && length(app.TapChangerLocationListBox.Value) < tapChanger_minSelect || length(app.TapChangerLocationListBox.Value) > tapChanger_maxSelect)
                msgbox(sprintf("You can select a minimum of %d and a maximum of %d tap changer locations.", tapChanger_minSelect, tapChanger_maxSelect));
            elseif(currentMode == "2" && length(app.TapChangerLocationListBox.Value) == 0 && length(app.LocationsListBox.Value) == 0)
                msgbox(sprintf("Please select at least 1 battery or 1 tap changer location."));
            else
                % Clear all graphics
                cla(app.UIAxes,'reset');
                cla(app.DailyBatteryGraph,'reset');
                cla(app.DailyTapChangerGraph,'reset');
                cla(app.NonOptimizedGraph,'reset');
                cla(app.OptimizedGraph,'reset');
                
                
                app.LoadingImage.Visible = 1;
                
                app.UIAxes.Visible = 0;
                app.OptimizedGraph.Visible = 0;
                app.NonOptimizedGraph.Visible = 0;
                
                
                app.RunButton.Enable = 0;
                
                drawnow(); % refresh screen
                
                searchAgentCount = app.SearchAgentsSpinner.Value;
                iteration = app.IterationSpinner.Value;
                time = app.TimeDropDown.Value;
                algorithm = get(app.AlgorithmButtonGroup.SelectedObject,'Tag');
                pvBusesCount = app.DGperbusSpinner.Value;


                params{1} = testCase;
                params{2} = str2num(time);
                params{3} = cellfun(@str2num,app.LocationsListBox.Value); % bara konumları
                params{4} = searchAgentCount;
                params{5} = iteration;
                params{6} = algorithm;
                params{7} = pvBusesCount;
                
                if(debugMode)
                    params{8} = [1.128305, 1.142682, 1.162572, 1.184737, 1.230225, 1.305507, 1.375324, 1.372336, 1.350753, 1.272157, 1.252259, 1.000000, 0.850288, 0.741536, 0.674764, 0.656565, 0.701558, 0.906607, 0.856187, 0.938234, 1.014948, 1.082953, 1.078264, 1.123892];
                    params{9} = [0, 0, 0, 0, 0, 0, 0.057, 0.729, 1.564, 2.741, 4.246, 5.871, 6.261, 6.352, 6.097, 5.39, 3.872, 2.514, 1.107, 0.139, 0, 0, 0, 0];
                else
                    params{8} = str2double(regexp(dayTimeLoads, ', ', 'split'));
                    params{9} = str2double(regexp(dayTimePvOutputLoadVal, ', ', 'split'));
                end
                
                dayTimeLoadsValuesVector =  params{8};
                dayTimeLoadValue = params{8}(params{2});
                
                params{10} = cellfun(@str2num,app.TapChangerLocationListBox.Value);  % tap changer location
                params{11} = []; % pv and tap changers table
                
                % Batteries START
                
                params{12} = cellfun(@str2num,app.LocationsListBox.Value); % battery locations
                params{13} = pvBusesCount; % battery count
                params{14} = app.StepkWhSpinner.Value; % kWh Battery % input olacak, limit koymak gerekir mi?
                params{15} = app.InitialOutputkWSpinner.Value; % Battery start % Kaç kW ile başlıyoruz
                params{16} = [app.SoCLBkWSpinner.Value app.SoCUBkWSpinner.Value]; % Battery lb and ub % SoC lb ve ub
                
                % Batteries END
                
                TapPositions = [];
                
                loopCount = 1;
                if currentMode == '1'
                    loopCount = 1;
                else
                    loopCount = 24;
                end
                
                % for each tap changer
                for iii = 1:length(params{10})
                    
                    for hhh = 1:loopCount
                        %type %position %lb %ub
                        line = ["tc" params{10}(iii) -16 16];
                        % add to vector
                        params{11} = [params{11} ;line];
                    end
                    
                end
                
                if currentMode == '1' %dg
                    
                    % for each pv's
                    for iii = 1:length(params{3})
                        %type %position %lb %ub
                        line = ["dg" params{3}(iii) -6 6];
                        % add to vector
                        params{11}  = [params{11} ;line];
                    end
                    
                end
                
                if currentMode == '2' % battrey
                    
                    % for each battery
                    for iii = 1:length(params{12})
                        
                        % add parameters for each hour
                        for hhh = 1:loopCount
                            %type %position %lb %ub
                            line = ["battery" params{12}(iii) -1 0];
                            % add to vector
                            params{11} = [params{11} ;line];
                        end
                        
                    end
                    
                end
                
                if currentMode == '1' % Mode => DG + Battery
                    % run the case without optimization
                    [Py,Qy,BR]=GetCaseData(params{1});
                    
                    Py = Py * dayTimeLoadValue;
                    Qy = Qy * dayTimeLoadValue;
                    
                    defaultVoltage = CalculateVoltage(Py,Qy,BR,TapPositions);
                    
                    % run the case with optimization
                    
                    [lb,ub,dim,params,fobj]=Get_Functions_details('F99',params);
                    
                    Best_pos = [];
                    
                    switch algorithm
                        case 'GWO'
                            [Best_score,Best_pos]=GWO(searchAgentCount,iteration,lb,ub,dim,params,fobj);
                        case 'WOA'
                            [Best_score,Best_pos]=WOA(searchAgentCount,iteration,lb,ub,dim,params,fobj);
                        case 'ALO'
                            [Best_score,Best_pos]=ALO(searchAgentCount,iteration,lb,ub,dim,params,fobj);
                        case 'PSO'
                            [Best_score,Best_pos]=PSO(searchAgentCount,iteration,lb,ub,dim,params,fobj);
                    end
                    
                    for iii = 1:size(params{11},1)
                        line = params{11}(iii,:);
                        if line(1) == "tc"
                            Best_pos(1,iii) = round(Best_pos(1,iii));
                            newLine = [str2num(line(2)) Best_pos(1,iii)];
                            TapPositions  = [TapPositions;newLine];
                        elseif line(1) == "dg"
                            currentBus = str2num(line(2));
                            value = Best_pos(1,iii);
                            
                            Py(currentBus) = Py(currentBus) + (pvBusesCount * params{9}(params{2}));
                            
                            if(value < 0)
                                Qy(currentBus) = Qy(currentBus) - value;
                            else
                                Qy(currentBus) = Qy(currentBus) + value;
                            end
                        end
                    end
                    
                    
                    % Calculate optimized voltage
                    optimizedVoltage = CalculateVoltage(Py,Qy,BR,TapPositions);
                    
                    
                    app.UIAxes.Title.String = "Case= " + testCaseFriendlyText + " Time= " + num2str(str2num(time)-1)  + ":00 Algorithm= " + algorithm;
                    app.UIAxes.XLabel.String  = 'Bus number';
                    app.UIAxes.YLabel.String  = 'Voltage magnitude in pu';
                   
                    set(app.UIAxes, 'YTick', [0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1]);

                    hold( app.UIAxes, 'on' );
                    
                    p = plot(app.UIAxes, defaultVoltage);
                    p.LineWidth = 3;
                    
                    o = plot(app.UIAxes,optimizedVoltage);
                    
                    o.LineWidth = 3;
                    
                    horiziontalLine = yline(app.UIAxes,0.95,'-.black');
                    horiziontalLine.LineWidth = 3;
                    
                    legend(app.UIAxes,'Base Case','Near-optimal Case');
                    hold( app.UIAxes, 'off' );
                    
                    
                    app.UIAxes.Visible = 1;
                else %% else if
                    
                    BatteryLB = params{16}(1);
                    BatteryUB = params{16}(2);
                    BatteryStartValue = params{15};
                    BatteryStep = params{14};
                    
                    [lb,ub,dim,params,fobj]=Get_Functions_details('F100',params);
                    
                    switch algorithm
                        case 'GWO'
                            [Best_score,Best_pos]=GWO(searchAgentCount,iteration,lb,ub,dim,params,fobj);
                        case 'WOA'
                            [Best_score,Best_pos]=WOA(searchAgentCount,iteration,lb,ub,dim,params,fobj);
                        case 'ALO'
                            [Best_score,Best_pos]=ALO(searchAgentCount,iteration,lb,ub,dim,params,fobj);
                        case 'PSO'
                            [Best_score,Best_pos]=PSO(searchAgentCount,iteration,lb,ub,dim,params,fobj);
                    end
                    
                    % params 12 battery loc
                    % params 10 tc loc
                    EditedPositions = reshape(Best_pos,[], (length(params{10})+ length(params{12})))';
                    

                    hold( app.DailyBatteryGraph, 'on' );
                    
                    %DailyBatteryGraph
                    for iii = 1:length(params{12})
                        zzz = (length(params{10}) + iii);
                        BatteryStart = BatteryStartValue;
                        BatteryHouryLoads = zeros(1,24);
                        
                        for ttt = 1:24
                            BatteryStart = BatteryStart + (BatteryStep * EditedPositions(zzz,ttt));
                            BatteryHouryLoads(ttt) = BatteryStart;
                        end
                        
                        p = stairs(app.DailyBatteryGraph, BatteryHouryLoads);
                        
                        p.LineWidth  = 3;
                        
                    end
                    
                    ylabel(app.DailyBatteryGraph, 'Battery power (in kW) ');
                    xlabel(app.DailyBatteryGraph, 'Time of day (in hours)');
                    
                    hold( app.DailyBatteryGraph, 'off' );
                    
                    hold( app.DailyTapChangerGraph, 'on' );
                    %DailyTapChangerGraph
                    for iii = 1:length(params{10})
                        p = stairs(app.DailyTapChangerGraph, EditedPositions(iii,:));
                        p.LineWidth  = 3;
                    end
                    
                    ylabel(app.DailyTapChangerGraph, 'Tap changer position');
                    xlabel(app.DailyTapChangerGraph, 'Time of day (in hours)');
                    
                    
                    hold( app.DailyTapChangerGraph, 'off' );
                    
                    
                    hold( app.NonOptimizedGraph, 'on' );
                    % non optimized case
                    for iii = 1:24
                        [Py,Qy,BR]=GetCaseData(params{1});
                        
                        Py = Py * params{8}(iii);
                        Qy = Qy * params{8}(iii);
                        
                        defaultVoltage = CalculateVoltage(Py,Qy,BR,[]);
                        
                        plot(app.NonOptimizedGraph, defaultVoltage);
                    end
                    
                    
                    ylabel(app.NonOptimizedGraph, 'Voltage magnitude in pu');
                    xlabel(app.NonOptimizedGraph, 'Bus number');
                    
                    
                    hold( app.NonOptimizedGraph, 'off' );
                    
                    
                    hold( app.OptimizedGraph, 'on' );
                    % optimized case
                    for iii = 1:24
                        [Py,Qy,BR]=GetCaseData(params{1});
                        
                        Py = Py * params{8}(iii);
                        Qy = Qy * params{8}(iii);
                        
                        
                        CurrentTimeTapLocations = zeros(length(params{10}), 2); % 2 sabit olacak.
                        
                        for ttt = 1:length(params{10})
                            EditedPositions(ttt,iii) = round(EditedPositions(ttt,iii));
                            CurrentTimeTapLocations(ttt,:) = [params{10}(ttt) EditedPositions(ttt,iii)];
                        end
                        
                        for jjj = 1:length(params{12})
                            zzz = (length(params{10}) + jjj);
                            currentBatteryPowerAtThisTime = EditedPositions(zzz,iii);
                            busLocation = params{12}(jjj);
                            Py(busLocation) = Py(busLocation) - (params{13} *  BatteryStep * currentBatteryPowerAtThisTime);
                        end
                        
                        optimizedVoltage = CalculateVoltage(Py,Qy,BR,CurrentTimeTapLocations);
                        
                        
                        plot(app.OptimizedGraph, optimizedVoltage);
                    end
                    
                    ylabel(app.OptimizedGraph, 'Voltage magnitude in pu');
                    xlabel(app.OptimizedGraph, 'Bus number');
                    
                    hold( app.OptimizedGraph, 'off' );
                    
                    app.OptimizedGraph.Visible = 1;
                    app.NonOptimizedGraph.Visible = 1;
                    
                end
                
                app.LoadingImage.Visible = 0;
                app.RunButton.Enable = 1;
            end
            
            
        end

        % Selection changed function: TestSystemButtonGroup
        function TestSystemButtonGroupSelectionChanged(app, event)
            selectedButton = app.TestSystemButtonGroup.SelectedObject;
            global bus_minSelect
            global bus_maxSelect
            global maxBus
            global tapChanger_minSelect
            global tapChanger_maxSelect
            global testCase
            global testCaseFriendlyText
            global currentMode
            
            switch get(app.TestSystemButtonGroup.SelectedObject,'Tag')
                case '33',  testCase = 'case33bw'; testCaseFriendlyText = '33 Bus'; maxBus = 33; bus_minSelect = 3; bus_maxSelect = 6; tapChanger_minSelect=0; tapChanger_maxSelect = 3;
                case '69',  testCase = 'case69';  testCaseFriendlyText = '69 Bus'; maxBus = 69; bus_minSelect = 5; bus_maxSelect = 10; tapChanger_minSelect=0; tapChanger_maxSelect = 3;
                case '141', testCase = 'case141'; testCaseFriendlyText = '141 Bus';  maxBus = 141; bus_minSelect = 7; bus_maxSelect = 14; tapChanger_minSelect=0; tapChanger_maxSelect = 3;
            end
            
            app.HTML.HTMLSource = strcat('CasePreview/preview-',testCase,'.html');
            
            if currentMode == '1'
                app.Image.ImageSource = 'CasePreview/Legend.svg';
            else
                app.Image.ImageSource = 'CasePreview/Legend_Battery.svg';
            end
            
            
            resetLocationListBoxes(app,string(1:maxBus),string(2:maxBus),0);
            
        end

        % Value changed function: LocationsListBox
        function LocationsListBoxValueChanged(app, event)
            value = app.LocationsListBox.Value;
            app.SelectedTextAreaLabel.Visible = 1;
            app.SelectedTextArea.Visible = 1;
            app.SelectedTextArea.Value =  value;
            
            app.HTML.Data = ["dg" cellfun(@str2num,value)];
            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            global dayTimeLoads
            [filename, filepath] = uigetfile({'*.txt';'*.csv'}, 'Load');
            figure(app.UIFigure)
            fullpath = append(filepath,filename);
            app.HourlyLoadProfileFileField.Value = fullpath;
            filecontent = fopen(fullpath,'r');
            while ~feof(filecontent)
                tline = fgetl(filecontent);
                dayTimeLoads = tline;
            end
            fclose(filecontent);
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            global dayTimePvOutputLoadVal
            [filename, filepath] = uigetfile({'*.txt';'*.csv'}, 'DG Load');
            figure(app.UIFigure)
            fullpath = append(filepath,filename);
            app.HourlyPVLoadProfileFileField.Value = fullpath;
            filecontent = fopen(fullpath,'r');
            while ~feof(filecontent)
                tline = fgetl(filecontent);
                dayTimePvOutputLoadVal = tline;
            end
        end

        % Value changed function: TapChangerLocationListBox
        function TapChangerLocationListBoxValueChanged(app, event)
            value = app.TapChangerLocationListBox.Value;
            
            app.SelectedTapChangerTextAreaLabel.Visible = 1;
            app.SelectedTapChangerTextArea.Visible = 1;
            app.SelectedTapChangerTextArea.Value =  value;
            
            app.HTML.Data = ["tc" cellfun(@str2num,value)];
        end

        % Button pushed function: ResetSelectedDGsButton
        function ResetSelectedDGsButtonPushed(app, event)
            global maxBus
            resetLocationListBoxes(app,string(1:maxBus),string(2:maxBus),1);
            app.HTML.Data = ["dg"];
        end

        % Button pushed function: ResetSelectedTapChangerButton
        function ResetSelectedTapChangerButtonPushed(app, event)
            global maxBus
            resetLocationListBoxes(app,string(1:maxBus),string(2:maxBus),2);
            app.HTML.Data = ["tc"];
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            url = 'https://github.com/alperenozlu/load-flow-analysis-gui/wiki';
            web(url);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            about;
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            global currentMode;
            
            selectedButton = app.ModeButtonGroup.SelectedObject;
            tag = get(app.ModeButtonGroup.SelectedObject,'Tag');
            
            if tag  == '1'
                
                cla(app.DailyBatteryGraph,'reset');
                cla(app.DailyTapChangerGraph,'reset');
                cla(app.NonOptimizedGraph,'reset');
                cla(app.OptimizedGraph,'reset');
                
                app.UIAxes.Visible = true;
                app.TimeDropDown.Enable = true;
                app.MinMaxLabel.Visible = true;
                app.ThesesettingsareonlyavailableforbatterytapchangermodeLabel.Visible = true;
                
                app.DistributionGridsTab.Title = 'Distribution Grids';
                
                app.DGperbusSpinnerLabel.Text = 'DG per bus';
                
                app.BatterySettingsPanel.Visible = false;
                app.DailyBatteryGraph.Visible = false;
                app.DailyTapChangerGraph.Visible = false;
                app.OptimizedGraph.Visible = false;
                app.NonOptimizedGraph.Visible = false;
                app.BatterySettingsPanel.Visible = false;
                
                app.Image.ImageSource = 'CasePreview/Legend.svg';
                currentMode = '1';
            else
                cla(app.UIAxes,'reset');
                
                app.UIAxes.Visible = false;
                
                app.TimeDropDown.Enable = false;
                app.MinMaxLabel.Visible = false;
                app.ThesesettingsareonlyavailableforbatterytapchangermodeLabel.Visible = false;
                
                
                app.DistributionGridsTab.Title = 'Batteries';
                app.DGperbusSpinnerLabel.Text = 'Battery per bus';
                
                app.BatterySettingsPanel.Visible = true;
                
                app.DailyBatteryGraph.Visible = true;
                app.DailyTapChangerGraph.Visible = true;
                app.OptimizedGraph.Visible = true;
                app.NonOptimizedGraph.Visible = true;
                app.BatterySettingsPanel.Visible = true;


                app.Image.ImageSource = 'CasePreview/Legend_Battery.svg';
                currentMode = '2';
            end
            
            
            drawnow();
            
            
        end

        % Callback function
        function LoadingImageClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.Panel = uipanel(app.UIFigure);
            app.TestSystemButtonGroup = uibuttongroup(app.Panel);
            app.TestSystemButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TestSystemButtonGroupSelectionChanged, true);
            app.TestSystemButtonGroup.Title = 'Test System';
            app.BusButton = uiradiobutton(app.TestSystemButtonGroup);
            app.BusButton.Tag = '33';
            app.BusButton.Text = '33 Bus';
            app.BusButton.Value = true;
            app.BusButton_2 = uiradiobutton(app.TestSystemButtonGroup);
            app.BusButton_2.Tag = '69';
            app.BusButton_2.Text = '69 Bus';
            app.BusButton_3 = uiradiobutton(app.TestSystemButtonGroup);
            app.BusButton_3.Tag = '141';
            app.BusButton_3.Text = '141 Bus';
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.LoadingImage = uiimage(app.Panel);
            app.LoadingImage.Visible = 'off';
            app.LoadingImage.ImageSource = 'loading.gif';
            app.AlgorithmButtonGroup = uibuttongroup(app.Panel);
            app.AlgorithmButtonGroup.Title = 'Algorithm';
            app.GWOButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.GWOButton.Tag = 'GWO';
            app.GWOButton.Text = 'GWO';
            app.GWOButton.Value = true;
            app.WOAButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.WOAButton.Tag = 'WOA';
            app.WOAButton.Text = 'WOA';
            app.ALOButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.ALOButton.Tag = 'ALO';
            app.ALOButton.Text = 'ALO';
            app.PSOButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.PSOButton.Tag = 'PSO';
            app.PSOButton.Text = 'PSO';
            app.ModeButtonGroup = uibuttongroup(app.Panel);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.DGTapChangerButton = uiradiobutton(app.ModeButtonGroup);
            app.DGTapChangerButton.Tag = '1';
            app.DGTapChangerButton.Text = 'DG + Tap Changer';
            app.DGTapChangerButton.Value = true;
            app.BatteryTapChangerButton = uiradiobutton(app.ModeButtonGroup);
            app.BatteryTapChangerButton.Tag = '2';
            app.BatteryTapChangerButton.Text = 'Battery + Tap Changer';
            app.HourlyLoadProfilePanel = uipanel(app.Panel);
            app.HourlyLoadProfilePanel.Title = 'Hourly Load Profile';
            app.HourlyLoadProfileFileField = uieditfield(app.HourlyLoadProfilePanel, 'text');
            app.HourlyLoadProfileFileField.Editable = 'off';
            app.SelectButton = uibutton(app.HourlyLoadProfilePanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.HourlyLoadPVProfilePanel = uipanel(app.Panel);
            app.HourlyLoadPVProfilePanel.Title = 'Hourly Load PV Profile';
            app.HourlyPVLoadProfileFileField = uieditfield(app.HourlyLoadPVProfilePanel, 'text');
            app.HourlyPVLoadProfileFileField.Editable = 'off';
            app.SelectButton_2 = uibutton(app.HourlyLoadPVProfilePanel, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.TabGroup = uitabgroup(app.Panel);
            app.DistributionGridsTab = uitab(app.TabGroup);
            app.DistributionGridsTab.Title = 'Distribution Grids';
            app.MinMaxLabel = uilabel(app.DistributionGridsTab);
            app.MinMaxLabel.Text = 'MinMax';
            app.LocationsListBoxLabel = uilabel(app.DistributionGridsTab);
            app.LocationsListBoxLabel.Text = 'Locations';
            app.LocationsListBox = uilistbox(app.DistributionGridsTab);
            app.LocationsListBox.Items = {};
            app.LocationsListBox.Multiselect = 'on';
            app.LocationsListBox.ValueChangedFcn = createCallbackFcn(app, @LocationsListBoxValueChanged, true);
            app.LocationsListBox.Value = {};
            app.SelectedTextAreaLabel = uilabel(app.DistributionGridsTab);
            app.SelectedTextAreaLabel.Text = 'Selected Locations';
            app.SelectedTextArea = uitextarea(app.DistributionGridsTab);
            app.SelectedTextArea.Editable = 'off';
            app.DGperbusSpinnerLabel = uilabel(app.DistributionGridsTab);
            app.DGperbusSpinnerLabel.Text = 'DG per bus';
            app.DGperbusSpinner = uispinner(app.DistributionGridsTab);
            app.DGperbusSpinner.Limits = [0 20];
            app.DGperbusSpinner.Value = 8;
            app.ResetSelectedDGsButton = uibutton(app.DistributionGridsTab, 'push');
            app.ResetSelectedDGsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSelectedDGsButtonPushed, true);
            app.ResetSelectedDGsButton.Text = 'RESET';
            app.TapChangersTab = uitab(app.TabGroup);
            app.TapChangersTab.Title = 'Tap Changers';
            app.TapChangerLocationLabel = uilabel(app.TapChangersTab);
            app.TapChangerLocationLabel.Text = 'Locations';
            app.TapChangerLocationListBox = uilistbox(app.TapChangersTab);
            app.TapChangerLocationListBox.Items = {};
            app.TapChangerLocationListBox.Multiselect = 'on';
            app.TapChangerLocationListBox.ValueChangedFcn = createCallbackFcn(app, @TapChangerLocationListBoxValueChanged, true);
            app.TapChangerLocationListBox.Value = {};
            app.TapChangerMinMaxLabel = uilabel(app.TapChangersTab);
            app.TapChangerMinMaxLabel.Text = 'MinMax';
            app.SelectedTapChangerTextAreaLabel = uilabel(app.TapChangersTab);
            app.SelectedTapChangerTextAreaLabel.Text = 'Selected Locations';
            app.SelectedTapChangerTextArea = uitextarea(app.TapChangersTab);
            app.SelectedTapChangerTextArea.Editable = 'off';
            app.ResetSelectedTapChangerButton = uibutton(app.TapChangersTab, 'push');
            app.ResetSelectedTapChangerButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSelectedTapChangerButtonPushed, true);
            app.ResetSelectedTapChangerButton.Text = 'RESET';
            app.BatterySettingsTab = uitab(app.TabGroup);
            app.BatterySettingsTab.Title = 'Battery Settings';
            app.BatterySettingsPanel = uipanel(app.BatterySettingsTab);
            app.BatterySettingsPanel.Visible = 'off';
            app.StepkWhSpinnerLabel = uilabel(app.BatterySettingsPanel);
            app.StepkWhSpinnerLabel.Text = 'Step (kWh)';
            app.StepkWhSpinner = uispinner(app.BatterySettingsPanel);
            app.StepkWhSpinner.Limits = [0 Inf];
            app.StepkWhSpinner.Value = 5;
            app.SoCLBkWSpinnerLabel = uilabel(app.BatterySettingsPanel);
            app.SoCLBkWSpinnerLabel.Text = 'SoC LB  (kW)';
            app.SoCLBkWSpinner = uispinner(app.BatterySettingsPanel);
            app.SoCLBkWSpinner.Limits = [0 100];
            app.SoCLBkWSpinner.Value = 20;
            app.SoCUBkWSpinnerLabel = uilabel(app.BatterySettingsPanel);
            app.SoCUBkWSpinnerLabel.Text = 'SoC UB (kW)';
            app.SoCUBkWSpinner = uispinner(app.BatterySettingsPanel);
            app.SoCUBkWSpinner.Limits = [0 100];
            app.SoCUBkWSpinner.Value = 80;
            app.InitialOutputkWSpinnerLabel = uilabel(app.BatterySettingsPanel);
            app.InitialOutputkWSpinnerLabel.Text = 'Initial Output (kW)';
            app.InitialOutputkWSpinner = uispinner(app.BatterySettingsPanel);
            app.InitialOutputkWSpinner.Limits = [0 100];
            app.InitialOutputkWSpinner.Value = 30;
            app.ThesesettingsareonlyavailableforbatterytapchangermodeLabel = uilabel(app.BatterySettingsTab);
            app.ThesesettingsareonlyavailableforbatterytapchangermodeLabel.Text = 'These settings are only available for battery + tap changer mode. ';
            app.Panel_2 = uipanel(app.Panel);
            app.SearchAgentsSpinnerLabel = uilabel(app.Panel_2);
            app.SearchAgentsSpinnerLabel.Text = 'Search Agents';
            app.SearchAgentsSpinner = uispinner(app.Panel_2);
            app.SearchAgentsSpinner.Limits = [0 100];
            app.SearchAgentsSpinner.Value = 50;
            app.IterationSpinnerLabel = uilabel(app.Panel_2);
            app.IterationSpinnerLabel.Text = 'Iteration';
            app.IterationSpinner = uispinner(app.Panel_2);
            app.IterationSpinner.Limits = [0 10000];
            app.IterationSpinner.Value = 1000;
            app.TimeDropDownLabel = uilabel(app.Panel_2);
            app.TimeDropDownLabel.Text = 'Time';
            app.TimeDropDown = uidropdown(app.Panel_2);
            app.TimeDropDown.Items = {'0:00', '1:00', '2:00', '3:00', '4:00', '5:00', '6:00', '7:00', '8:00', '9:00', '10:00', '11:00', '12:00', '13:00', '14:00', '15:00', '16:00', '17:00', '18:00', '19:00', '20:00', '21:00', '22:00', '23:00', ''};
            app.TimeDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.TimeDropDown.Value = '18';
            app.PreviewPanel = uipanel(app.UIFigure);
            app.PreviewPanel.Title = 'Preview';
            app.HTML = uihtml(app.PreviewPanel);
            app.HTML.Tag = 'preview';
            app.Image = uiimage(app.PreviewPanel);
            app.Image.ImageSource = 'Legend.svg';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.ResultTab = uitab(app.TabGroup2);
            app.ResultTab.Title = 'Result';
            app.NonOptimizedGraph = uiaxes(app.ResultTab);
            title(app.NonOptimizedGraph, 'Non Optimized')
            xlabel(app.NonOptimizedGraph, 'X')
            ylabel(app.NonOptimizedGraph, 'Y')
            zlabel(app.NonOptimizedGraph, 'Z')
            app.NonOptimizedGraph.Visible = 'off';
            app.OptimizedGraph = uiaxes(app.ResultTab);
            title(app.OptimizedGraph, 'Optimized')
            xlabel(app.OptimizedGraph, 'X')
            ylabel(app.OptimizedGraph, 'Y')
            zlabel(app.OptimizedGraph, 'Z')
            app.OptimizedGraph.Visible = 'off';
            app.UIAxes = uiaxes(app.ResultTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DailyBatteryChangeTab = uitab(app.TabGroup2);
            app.DailyBatteryChangeTab.Title = 'Daily Battery (Change)';
            app.DailyBatteryGraph = uiaxes(app.DailyBatteryChangeTab);
            title(app.DailyBatteryGraph, 'Daily Battery')
            xlabel(app.DailyBatteryGraph, 'kW')
            ylabel(app.DailyBatteryGraph, 'Hour')
            zlabel(app.DailyBatteryGraph, 'Z')
            app.DailyBatteryGraph.Visible = 'off';
            app.DailyTapChangerChangeTab = uitab(app.TabGroup2);
            app.DailyTapChangerChangeTab.Title = 'Daily Tap Changer (Change)';
            app.DailyTapChangerGraph = uiaxes(app.DailyTapChangerChangeTab);
            title(app.DailyTapChangerGraph, 'Daily Tap Changer')
            xlabel(app.DailyTapChangerGraph, 'X')
            ylabel(app.DailyTapChangerGraph, 'Y')
            zlabel(app.DailyTapChangerGraph, 'Z')
            app.DailyTapChangerGraph.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end